classdef gotowe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_4                   matlab.ui.control.UIAxes
        WczytajzdjcieButton        matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        Slider                     matlab.ui.control.Slider
        ButtonGroup                matlab.ui.container.ButtonGroup
        JasnoButton                matlab.ui.control.RadioButton
        KontrastButton             matlab.ui.control.RadioButton
        GammaButton                matlab.ui.control.RadioButton
        RotacjaButton              matlab.ui.control.RadioButton
        SkalowanieButton           matlab.ui.control.RadioButton
        CzarnoBiaeButton           matlab.ui.control.RadioButton
        OdbicieHoryzontalneButton  matlab.ui.control.RadioButton
        OdbicieWertykalneButton    matlab.ui.control.RadioButton
        OdbicieWHButton            matlab.ui.control.RadioButton
        PseudokolorwanieButton     matlab.ui.control.RadioButton
        ZapiszzmianyButton         matlab.ui.control.Button
        ZapiszdoplikuButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        imageOrg
        imageCpy
        
        sliderValue % Description
        selectedButton % Description
    end
    
    methods (Access = private)
        
        function results = drawAll(app)                    
            imshow(app.imageOrg, 'parent', app.UIAxes)
            histogram(app.imageOrg, 'parent', app.UIAxes_3)
            imshow(app.imageCpy, 'parent', app.UIAxes_2)
            histogram(app.imageCpy, 'parent', app.UIAxes_4)
        end
        
                function results = drawCopy(app)                    
            imshow(app.imageCpy, 'parent', app.UIAxes_2)
            histogram(app.imageCpy, 'parent', app.UIAxes_4)
                end
                
        function imgCopy2 = jasnosc(app, imgCopy, val)
        
        %JASNOSC zmienia jasnosc obrazu
        %   IMGCOPY2 = JASNOSC(IMGCOPY, VAL) zwraca obraz IMGCOPY ze zmienion¹
        %   jasnoci¹ o wartoæ VAL
        %   
        %   Wartoci VAL z przedzia³u od -1 do 1
        %   Dla wartoci mniejszych od 0 jasnoæ obrazu siê zmniejsza
        %   Dla wartoci wiêkszych od 0 jestoæ obrazu siê zwiêksza

        imgCopy2 = imgCopy + val;
        
        end
        
        function imgCopy2 = kontrast(app, imgCopy, val)
        
        %KONTRAST zmienia kontrast obrazu
        %   IMGCOPY2 = KONTRAST(IMGCOPY, VAL) zwraca obraz IMGCOPY ze zmienionym
        %   kontrastem o wartoæ VAL
        %   
        %   Wartoci VAL z przedzia³u od 0 do +inf
        %   Dla wartoci mniejszych od 1 kontrast obrazu ulega zmniejszeniu
        %   Dla wartoci wiêkszych od 1 kontrast obrazu ulega zwiêkszeniu
        
        
        imgCopy2 = imgCopy * val;
        
        end
        
        function imgCopy2 = rotacja(app, imgCopy, val)
            
            %ROTACJA obraca obraz
            %   IMGCOPY2 = ROTACJA(IMGCOPY, VAL) zwraca obraz IMGCOPY obrócony o k¹t
            %   VAL skierowany dodatnio wzglêdnem osi poziomej.
            %   
            %   Wartoci VAL z przedzia³u od 0 do +inf wyra¿ona w radianach
            %   Pe³en obrót dla wartoci k * 2 * PI, k ca³kowite
            %
            %   Wymiary obrazu zostan¹ dopasowane do najmniejszego prostok¹ta
            %   zawieraj¹cego obrócony obraz, pozosta³a czêæ zostanie wype³niona
            %   kolorem czarnym (wartoæ równa 0).
            
            [size_x, size_y, size_z] = size(imgCopy);
            
            %   Ustalenie nowych wymiarów obrazu
            x_sin = size_x * abs(sin(val));
            y_sin = size_y * abs(sin(pi/2 - val));
            x_cos = size_x * abs(cos(val));
            y_cos = size_y * abs(cos(pi/2 - val));
            
            new_size_x = round(y_cos + x_cos);
            new_size_y = round(y_sin + x_sin);
            
            %   Ustalenie rodka przekszta³canego obrazu
            mid_x = size_x / 2;
            mid_y = size_y / 2;
            
            %   Ustalenie rodka nowego obrazu
            new_mid_x = new_size_x / 2;
            new_mid_y = new_size_y / 2;
            
            imgCopy2 = zeros(new_size_x, new_size_y, size_z);
            
            for x = 1 : new_size_x
                for y = 1 : new_size_y
                    %   Przejcie na wspó³rzêdne biegunowe
                    r = sqrt((x - new_mid_x)^2 + (y - new_mid_y)^2);
                    phi = atan2(y - new_mid_y, x - new_mid_x);
                    
                    %   Obrót o zadany k¹t
                    phi = phi - val;
                    
                    %   Powrót na wspó³rzêdne kartezjañskie
                    new_x = floor(r * cos(phi) + mid_x);
                    new_y = floor(r * sin(phi) + mid_y);
                    
                    for i = 1 : size_z
                        if new_x >= 1 && new_x <= size_x && new_y >= 1 && new_y <= size_y
                            %	Przepisanie punktów 
                            imgCopy2(x, y, i) = imgCopy(new_x, new_y, i);
                        end
                    end
                end
            end
            
        end
        
         function imgCopy2 = naszhistogram(app, b)
                a = b;%czarnobiale zdj;
                %a=rgb2gray(a);
                %gg = rgb2gray(a);
                r = size(a,1);
                c = size(a,2);
                ah = uint8(zeros(r,c));
                n=r*c;
                f=zeros(256,1);
                pdf=zeros(256,1);
                cdf=zeros(256,1);
                cum=zeros(256,1);
                out=zeros(256,1);
                
                for i =1:r
                	for j=1:c
                		value = a(i,j);
                		f(value+1)=f(value+1)+1;
                		pdf(value+1)=pdf(value+1)/n;
                	end
                end
                
                sum =0;L=255;
                
                for i = 1:size(pdf)
                	sum = sum +f(i);
                	cum(i)=sum;
                	cdf(i) = cum(i)/n;
                	out(i) = round(cdf(i)*L);
                end
                for i = 1:r
                	for j = 1:c
                		ah(i,j) = out(a(i,j)+1);
                	end
                end
                
                imgCopy2 = ah;
                
                end
        function imgCopy2 = gamma(app, imgCopy, val)
        
            imgCopy2 = imgCopy.^val;
        
        end

        function imgCopy2 = horizontalFlip(app, imgCopy)
            imgCopy2 = imgCopy(:,end:-1:1,:);           %# horizontal flip
        end
        function imgCopy2 = verticalFlip(app, imgCopy)
         imgCopy2 = imgCopy(end:-1:1,:,:);           %# horizontal flip
        end   
        function imgCopy2 = horizontalVerticalFlip(app, imgCopy)
            imgCopy2 = imgCopy(end:-1:1,end:-1:1,:);           %# horizontal flip
        end
        
        function imgCopy2 = pseudokolor(app, img)
                    
                    y = img;
                    y=rgb2gray(y);
                    [p,q,r]=size(y);
                        for i = 1:1:p
                            for j = 1:1:q
                                if (y(i,j)>=0 ) && (y(i,j)< 18)
                                	x(i,j,1)=0;
                                	x(i,j,2) = 0;
                                	x(i,j,3) = 0;
                                elseif (y(i,j)>=18 ) && (y(i,j)<36 )
                                	x(i,j,1)=237;
                                	x(i,j,2) = 1;
                                	x(i,j,3) = 25;
                                elseif (y(i,j)>= 36) && (y(i,j)<54 )
                                    x(i,j,1)=228;
                                    x(i,j,2) = 142;
                                	x(i,j,3) = 31;
                            	elseif (y(i,j)>= 54) && (y(i,j)<72 )
                            		x(i,j,1)=56;
                            		x(i,j,2) = 254;
                            		x(i,j,3) = 182;
                            	elseif (y(i,j)>=72 ) && (y(i,j)<90 )
                                	x(i,j,1)=21;
                                	x(i,j,2) = 158;
                            		x(i,j,3) = 233;
                            	elseif (y(i,j)>=90 ) && (y(i,j)<108 )
                            		x(i,j,1)=118;
                            		x(i,j,2) = 3;
                               		x(i,j,3) = 50;
                            	elseif (y(i,j)>= 108) && (y(i,j)< 126)
                                	x(i,j,1)=252;
                                	x(i,j,2) = 236;
                                	x(i,j,3) = 170;
                                elseif (y(i,j)>=126 ) && (y(i,j)<144 )
                                	x(i,j,1)=200;
                            		x(i,j,2) = 200;
                                	x(i,j,3) = 200;
                            	elseif (y(i,j)>= 144) && (y(i,j)<162 )
                                	x(i,j,1)=50;
                                	x(i,j,2) = 230;
                                	x(i,j,3) = 62;
                            	elseif (y(i,j)>= 162) && (y(i,j)<180 )
                                	x(i,j,1)=50;
                                	x(i,j,2) = 165;
                                	x(i,j,3) = 77;
                            	elseif (y(i,j)>= 180) && (y(i,j)<198 )
                                	x(i,j,1)=50;
                                	x(i,j,2) = 80;
                                	x(i,j,3) = 100;
                                elseif (y(i,j)>=198 ) && (y(i,j)<216 )
                                	x(i,j,1)=130;
                                	x(i,j,2) = 795;
                                	x(i,j,3) = 67;
                                elseif (y(i,j)>=216 ) && (y(i,j)<232 )
                                	x(i,j,1)=16;
                                	x(i,j,2) = 233;
                                	x(i,j,3) = 59;
                                elseif (y(i,j)>=232 ) && (y(i,j)<255 )
                                	x(i,j,1)=255;
                                	x(i,j,2) = 255;
                                    x(i,j,3) = 10;
                                end
                            end
                       
                        end
                        x = double(x/255);
                        imgCopy2=x;
                    end

        function imgCopy2 = skalowanie(app, imgCopy, val)

            %SKALOWANIE przeskalowuje obraz
            %   IMGCOPY2 = KONTRAST(IMGCOPY, VAL) zwraca obraz IMGCOPY z rozmiarem
            %   przeskalowanym przez wartoæ VAL
            %   
            %   Wartoci VAL z przedzia³u od 0 do +inf
            %   Dla wartoci mniejszych od 1 obraz ulega zmniejszeniu
            %   Dla wartoci wiêkszych od 1 obraz ulego powiêkszeniu
            %
            %   Algorytm wykorzystuje metodê najbli¿szego s¹siada - Nearest Neighbor
            
            [size_x, size_y, size_z] = size(imgCopy);
            
            %   Ustalenie nowych wymiarów obrazu
            new_size_x = floor(size_x * val);
            new_size_y = floor(size_y * val);
            
            
            imgCopy2 = zeros(new_size_x, new_size_y, size_z);
            
            for x = 1 : new_size_x
                for y = 1 : new_size_y
                    for i = 1 : size_z
                        % Metoda Nearest Neighbor
                        imgCopy2(x, y, i) = imgCopy(ceil(x / val), ceil(y / val), i);
                    end
                end
            end
            
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                        drawAll(app);

        end

        % Button pushed function: WczytajzdjcieButton
        function WczytajzdjcieButtonPushed(app, event)

            [filename pathname] = uigetfile({'*.jpg';'*.png'}, 'File Selector');
            fullpathname = strcat(pathname, filename);
            app.imageOrg = double (imread(fullpathname)) / 255;
            app.imageCpy = double (imread(fullpathname)) / 255;
            drawAll(app);

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            app.selectedButton = app.ButtonGroup.SelectedObject.Text;
            switch app.selectedButton
                case 'Odbicie Horyzontalne'
                    app.imageCpy = horizontalFlip(app, app.imageOrg);
                    drawCopy(app);
                case 'Odbicie Wertykalne'
                    app.imageCpy = verticalFlip(app, app.imageOrg);
                    drawCopy(app);
                case 'Odbicie W+H'
                    app.imageCpy = horizontalVerticalFlip(app, app.imageOrg);
                    drawCopy(app);
                case 'Czarno-Białe'
                    app.imageCpy = double(naszhistogram(app, 255*uint8(app.imageOrg))) / 255;
                    drawCopy(app);
                case 'Pseudokolorwanie'
                    app.imageCpy = double(pseudokolor(app, 255*uint8(app.imageOrg)));
                    drawCopy(app);
                end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
          app.sliderValue = event.Value;
          buttonText = app.ButtonGroup.SelectedObject.Text;
           switch buttonText
            case 'Jasność'

                val = interp1([0,100],[-1.0, 1.0], app.sliderValue);
                app.imageCpy = jasnosc(app, app.imageOrg, val);
                drawCopy(app);
            case 'Kontrast'
                app.imageCpy = kontrast(app, app.imageOrg, app.sliderValue);
                drawCopy(app);
                disp('zero')
            case 'Rotacja'
               val = interp1([0,100],[0, 2*pi], app.sliderValue);
               app.imageCpy = rotacja(app, app.imageOrg, val);
               drawCopy(app);
            case 'Skalowanie'
               val = interp1([0,100],[0, 2], app.sliderValue);
               app.imageCpy = skalowanie(app, app.imageOrg, val);
               drawCopy(app);

               
           case 'OdbicieH'
               app.imageCpy = horizontalFlip(app, app.imageOrg);
               drawCopy(app);
          case 'OdbicieW'
               app.imageCpy = verticalFlip(app, app.imageOrg);
               drawCopy(app);
          case 'OdbicieWH'
               app.imageCpy = horizontalVerticalFlip(app, app.imageOrg);
               drawCopy(app);
          case 'Gamma'
               val = interp1([0,100],[0, 10], app.sliderValue);
               app.imageCpy = gamma(app, app.imageOrg, val);
               drawCopy(app);

            otherwise
                disp('other value')
            end

            
        end

        % Button pushed function: ZapiszzmianyButton
        function ZapiszzmianyButtonPushed(app, event)
            app.imageOrg = app.imageCpy;
            drawAll(app);
        end

        % Button pushed function: ZapiszdoplikuButton
        function ZapiszdoplikuButtonPushed(app, event)
            filter = {'*.jpg';'*.png'};
            [file, path] = uiputfile(filter);
            filename = fullfile(path, file);
            imwrite(app.imageOrg, filename);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Zdjęcie')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Wersja robocza')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Histogram zdjęcia')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Histogram zdjęcia roboczego')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.WczytajzdjcieButton = uibutton(app.UIFigure, 'push');
            app.WczytajzdjcieButton.ButtonPushedFcn = createCallbackFcn(app, @WczytajzdjcieButtonPushed, true);
            app.WczytajzdjcieButton.Text = 'Wczytaj zdjęcie';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 50;
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.JasnoButton = uiradiobutton(app.ButtonGroup);
            app.JasnoButton.Text = 'Jasność';
            app.JasnoButton.Value = true;
            app.KontrastButton = uiradiobutton(app.ButtonGroup);
            app.KontrastButton.Text = 'Kontrast';
            app.GammaButton = uiradiobutton(app.ButtonGroup);
            app.GammaButton.Text = 'Gamma';
            app.RotacjaButton = uiradiobutton(app.ButtonGroup);
            app.RotacjaButton.Text = 'Rotacja';
            app.SkalowanieButton = uiradiobutton(app.ButtonGroup);
            app.SkalowanieButton.Text = 'Skalowanie';
            app.CzarnoBiaeButton = uiradiobutton(app.ButtonGroup);
            app.CzarnoBiaeButton.Text = 'Czarno-Białe';
            app.OdbicieHoryzontalneButton = uiradiobutton(app.ButtonGroup);
            app.OdbicieHoryzontalneButton.Text = 'Odbicie Horyzontalne';
            app.OdbicieWertykalneButton = uiradiobutton(app.ButtonGroup);
            app.OdbicieWertykalneButton.Text = 'Odbicie Wertykalne';
            app.OdbicieWHButton = uiradiobutton(app.ButtonGroup);
            app.OdbicieWHButton.Text = 'Odbicie W+H';
            app.PseudokolorwanieButton = uiradiobutton(app.ButtonGroup);
            app.PseudokolorwanieButton.Text = 'Pseudokolorwanie';
            app.ZapiszzmianyButton = uibutton(app.UIFigure, 'push');
            app.ZapiszzmianyButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszzmianyButtonPushed, true);
            app.ZapiszzmianyButton.Text = 'Zapisz zmiany';
            app.ZapiszdoplikuButton = uibutton(app.UIFigure, 'push');
            app.ZapiszdoplikuButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszdoplikuButtonPushed, true);
            app.ZapiszdoplikuButton.Text = 'Zapisz do pliku';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gotowe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end